classdef Q9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Load the SLX file
            modelName = 'signals';
            load_system(modelName);
            
            % Find all lines in the model
            lines = find_system(modelName, 'SearchDepth', 1, 'FindAll', 'on', 'Type', 'line');
            disp(lines);
            lines_2 = find_system(modelName,'SearchDepth',1,'Type','line');
            disp("new");
            disp(lines_2);

            sourceBlocks = get_param(lines, 'SrcBlockHandle');
            destinationBlocks = get_param(lines, 'DstBlockHandle');
            
            % Combine source and destination blocks
            allBlocksHandles = unique([cell2mat(sourceBlocks); cell2mat(destinationBlocks)]);
            %%signalNames = get_param(allBlocksHandles, 'Name');
            signalNames = {};
                for i = 1:numel(allBlocksHandles)
                    signalName = get_param(allBlocksHandles{i}, 'Name');
                    if ~isempty(signalName)
                        signalNames = [signalNames; signalName];
                    end
                end
                
                % Display the unique signal names
                disp('Signal Names:');
                disp(signalNames);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end