classdef Q9_final_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UnnamedSignalsCountEditField  matlab.ui.control.NumericEditField
        UnnamedSignalsCountEditFieldLabel  matlab.ui.control.Label
        ModelPathEditField            matlab.ui.control.EditField
        ModelPathEditFieldLabel       matlab.ui.control.Label
        NameSignalButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NameSignalButton
        function NameSignalButtonPushed(app, event)
            %% Path Selection
            [files, path] = uigetfile('*.*', 'Select Files', 'MultiSelect', 'on');
            
            % Check if the user canceled file selection
            if isequal(files, 0)
                disp('User selected Cancel');
            else

             if ~iscell(files)
                  files = {files};
             end
            
             disp(files{1});
             folder = fileparts(fullfile(path, files{1}));
             filename = fullfile(path,files{1});
             [filepath,name,ext] = fileparts(filename);

             disp(name);
             
             
             % Update EditField with the selected folder
             app.ModelPathEditField.Value = string(folder);
            end
            addpath(filepath);
            load_system(name);
            %% Subsytem için
            %%allBlocks = find_system(name,'SearchDepth', 1, 'Type', 'Block');
            %%disp(allBlocks);
            %{
            H = find_system(name,'FindAll','On','blocktype','subsystem');
            disp(H);
            signalNames_3 = get_param(H, 'Name');
            disp(signalNames_3);
            
            %%lines_2 = find_system('signals/Subsystem', 'SearchDepth', 1, 'FindAll', 'on', 'Type', 'line');
            %%disp(lines_2);
            %%signalNames_2 = get_param(lines_2, 'Name');
            %%disp(signalNames_2);
            %}
            %%
            %%allBlocks = find_system(name,'FindAll', 'on', 'blocktype', 'subsystem');
            %%disp(allBlocks);
          
            
            % Find all lines in the model
            lines = find_system(name, 'FindAll', 'on', 'Type', 'line');
            
            % Extract signal names from the lines
            signalNames = get_param(lines, 'Name');
            
            % Display the unique signal names
            disp('Signal Names:');
            disp(signalNames);

            lines_length = length(lines);

            disp(lines_length);
            named = "CSI_";
            count = 0;
            for i =1:lines_length
                currentName = get_param(lines(i), 'Name');
                disp(currentName);
                 if isempty(currentName)
                     disp(i);
                     disp("girdi");
                    % Generate a new name or set a specific name as needed
                    newName = named + count;
                    count = count +1;
                    % Set the new name for the line
                    set_param(lines(i), 'Name', newName);
                 end
            end
            app.UnnamedSignalsCountEditField.Value = count;
            signalNames_2 = get_param(lines, 'Name');
            %%save_system(name);
            disp(signalNames_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NameSignalButton = uibutton(app.UIFigure, 'push');
            app.NameSignalButton.ButtonPushedFcn = createCallbackFcn(app, @NameSignalButtonPushed, true);
            app.NameSignalButton.Text = 'Name Signal';
            app.ModelPathEditFieldLabel = uilabel(app.UIFigure);
            app.ModelPathEditFieldLabel.Text = 'Model Path';
            app.ModelPathEditField = uieditfield(app.UIFigure, 'text');
            app.UnnamedSignalsCountEditFieldLabel = uilabel(app.UIFigure);
            app.UnnamedSignalsCountEditFieldLabel.Text = 'Unnamed Signals Count';
            app.UnnamedSignalsCountEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q9_final_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end