classdef add_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [files, path] = uigetfile('*.*', 'Select Files', 'MultiSelect', 'on');
            
            % Check if the user canceled file selection
            if isequal(files, 0)
                disp('User selected Cancel');
            else

             if ~iscell(files)
                  files = {files};
             end
            end
            disp(files{1});
             folder = fileparts(fullfile(path, files{1}));
             filename = fullfile(path,files{1});
             [filepath,name,ext] = fileparts(filename);

             disp(name);
             disp(filepath);
             addpath(filepath);
             my_lib = 'new_lib.slx';

             set_param(gcs,'EnableLBRepository','on');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = add_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end