classdef q4_R2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea_2                  matlab.ui.control.TextArea
        TextArea_2Label             matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        EditField7                  matlab.ui.control.NumericEditField
        EditField6_2Label           matlab.ui.control.Label
        EditField6                  matlab.ui.control.NumericEditField
        EditField4_2Label_2         matlab.ui.control.Label
        EditField5                  matlab.ui.control.NumericEditField
        avgLabel                    matlab.ui.control.Label
        EditField4                  matlab.ui.control.NumericEditField
        EditField4Label             matlab.ui.control.Label
        EditField3                  matlab.ui.control.NumericEditField
        EditField3Label             matlab.ui.control.Label
        Team2countEditField         matlab.ui.control.EditField
        Team2countEditFieldLabel    matlab.ui.control.Label
        Team1countEditField         matlab.ui.control.EditField
        Team1countEditFieldLabel    matlab.ui.control.Label
        totalplayerEditField        matlab.ui.control.NumericEditField
        totalplayerEditFieldLabel   matlab.ui.control.Label
        ToplamRatingEditField       matlab.ui.control.NumericEditField
        ToplamRatingEditFieldLabel  matlab.ui.control.Label
        PlayerRatingsTextArea       matlab.ui.control.TextArea
        PlayerRatingsTextAreaLabel  matlab.ui.control.Label
        Button                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        textAreaValue = app.PlayerRatingsTextArea.Value;
        
        % Convert cell array of strings to a string array
        textAreaValueStrArray = string(textAreaValue);
        
        % Split the text into an array of lines

        totalSum = 0;
        linesArray = splitlines(textAreaValueStrArray);
        for i = 1:length(linesArray)
        % Extract numeric values using regular expression
        numericValues = str2double(regexp(linesArray{i}, '\d+(\.\d+)?', 'match'));
        disp(numericValues);
        
        % Add the numeric values to the sum
        totalSum = totalSum + sum(numericValues);
        end

        app.ToplamRatingEditField.Value = totalSum;

        player_count = length(linesArray);

        app.totalplayerEditField.Value = player_count;

        avg_player_rating = totalSum/length(linesArray);
        app.EditField5.Value = avg_player_rating;

        team_1_count = 0;

        team_2_count = 0;

        team_1_total = 0;

        team_2_total = 0;
        
        sorted_ratings = sort(linesArray,'descend');

        disp(sorted_ratings);
        
        team1 = {};
        team2 = {};
    
        % Iterate through sorted ratings and assign players to teams alternately
        for i = 1:length(sorted_ratings)
        if mod(i, 2) == 1
            team1 = [team1, sorted_ratings(i)];
        else
            team2 = [team2, sorted_ratings(i)];
        end
        
        end

        team1_total = 0;
        for i=1:length(team1)
            numericValues_1 = str2double(regexp(team1{i}, '\d+(\.\d+)?', 'match'));
            team1_total = team1_total + sum(numericValues_1);
        end
        
        disp(team1_total);

        %app.TextArea.Value = team1;

        new_rate = team1_total / length(team1);

        disp(new_rate);


        team2_total = 0;
        for i=1:length(team2)
            numericValues_2 = str2double(regexp(team2{i}, '\d+(\.\d+)?', 'match'));
            team2_total = team2_total + sum(numericValues_2);
        end
        
        disp(team2_total);
        
        new_rate_2 = team2_total / length(team2);

        disp(new_rate_2);


        if mod(player_count,2) == 0
            team_1_count = player_count/2;
            team_2_count = player_count/2;
        else
            team_1_count = ((player_count-1)/2);
            team_2_count = player_count - team_1_count;

        end
        
        app.Team1countEditField.Value = num2str(team_1_count);
        app.Team2countEditField.Value = num2str(team_2_count);
        %secme kısmı
        
        
        randomIndices = randperm(numel(linesArray));


        rand_team1 = linesArray(randomIndices(1:team_1_count));
        
        team_1_total = sum(str2double(rand_team1));

        avg_team_1 = team_1_total/team_1_count;

        app.EditField6.Value = avg_team_1;
        
        %disp(rand_team1);

        app.EditField3.Value =  team_1_total;

        team_2_total = totalSum - team_1_total;

        app.EditField4.Value = team_2_total;

        avg_team_2 = team_2_total / team_2_count;
        
        app.EditField7.Value = avg_team_2;  
    
        difference = abs(avg_team_1 - avg_team_2);

        if  difference == 0
            disp("equal");

        end

        %disp(difference);
            
            while (5< difference)
            %disp("not equal");
            randomIndices = randperm(numel(linesArray));
            
            rand_team1 = linesArray(randomIndices(1:team_1_count));
        
            team_1_total = sum(str2double(rand_team1));

            avg_team_1 = team_1_total/team_1_count;

            team_2_total = totalSum - team_1_total; 

            avg_team_2 = team_2_total / team_2_count;
            
            difference = abs(avg_team_1 - avg_team_2);
            end
            
        disp(randomIndices);

        sort_team_1 = sort(rand_team1,'ascend');

        app.TextArea.Value = sort_team_1;
        
        %rand_team_2 = linesArray - rand_team1;

        %sort_team_2 = sort(rand_team_2,'ascend');
        
        %app.TextArea_2.Value = sort_team_2;

        app.EditField3.Value =  team_1_total;
        
        app.EditField6.Value = avg_team_1;
        app.EditField4.Value = team_2_total;
        app.EditField7.Value = avg_team_2;  


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PlayerRatingsTextAreaLabel = uilabel(app.UIFigure);
            app.PlayerRatingsTextAreaLabel.Text = 'Player Ratings';
            app.PlayerRatingsTextArea = uitextarea(app.UIFigure);
            app.PlayerRatingsTextArea.Value = {'48'; '78'; '9'; '66'; '37'; '92'; '64'; '49'; '72'; '38'; '5'; '12'; '41'; '4'; '66'; '62'; '93'; '70'; '76'; '15'; '29'; '44'; '67'; '74'; '17'; '32'; '87'; '30'; '67'; '21'; '52'; '15'; '26'; '77'; '15'; '40'; '35'; '85'; '64'; '81'; '87'; '36'; '90'; '66'; '27'; '14'; '87'; '73'; '80'; '65'; '76'; '41'; '7'; '11'; '89'; '82'; '84'; '91'; '75'; '23'; '37'; '33'; '48'; '95'; '55'; '84'; '95'; '45'; '2'; '40'; '6'; '28'; '81'; '22'; '10'; '69'; '9'; '31'; '49'; '81'; '80'; '34'; '57'; '24'; '3'; '49'; '29'; '42'; '13'; '98'; '28'; '74'; '24'; '2'; '19'; '7'; '68'; '40'; '2'; '17'; '92'; '36'; '30'; '75'; '38'; '22'; '74'; '22'; '36'; '82'; '45'; '4'; '25'};
            app.ToplamRatingEditFieldLabel = uilabel(app.UIFigure);
            app.ToplamRatingEditFieldLabel.Text = 'Toplam Rating';
            app.ToplamRatingEditField = uieditfield(app.UIFigure, 'numeric');
            app.totalplayerEditFieldLabel = uilabel(app.UIFigure);
            app.totalplayerEditFieldLabel.Text = 'total player';
            app.totalplayerEditField = uieditfield(app.UIFigure, 'numeric');
            app.Team1countEditFieldLabel = uilabel(app.UIFigure);
            app.Team1countEditFieldLabel.Text = 'Team 1 count';
            app.Team1countEditField = uieditfield(app.UIFigure, 'text');
            app.Team2countEditFieldLabel = uilabel(app.UIFigure);
            app.Team2countEditFieldLabel.Text = 'Team 2 count';
            app.Team2countEditField = uieditfield(app.UIFigure, 'text');
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.avgLabel = uilabel(app.UIFigure);
            app.avgLabel.Text = 'avg';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4_2Label_2 = uilabel(app.UIFigure);
            app.EditField4_2Label_2.Text = 'Edit Field6';
            app.EditField6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField6_2Label = uilabel(app.UIFigure);
            app.EditField6_2Label.Text = 'Edit Field6';
            app.EditField7 = uieditfield(app.UIFigure, 'numeric');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q4_R2_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end