classdef q4_basic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofPlayersEditField_2     matlab.ui.control.NumericEditField
        NumberofPlayersEditField_2Label  matlab.ui.control.Label
        NumberofPlayersEditField       matlab.ui.control.NumericEditField
        NumberofPlayersEditFieldLabel  matlab.ui.control.Label
        Team2AvgEditField              matlab.ui.control.NumericEditField
        Team2AvgEditFieldLabel         matlab.ui.control.Label
        Team1AvgEditField              matlab.ui.control.NumericEditField
        Team1AvgEditFieldLabel         matlab.ui.control.Label
        Team2TextArea                  matlab.ui.control.TextArea
        Team2TextAreaLabel             matlab.ui.control.Label
        Team1TextArea                  matlab.ui.control.TextArea
        Team1TextAreaLabel             matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        textAreaValue = app.TextArea.Value;
        
        % Convert cell array of strings to a string array
        textAreaValueStrArray = string(textAreaValue);
        
        % Split the text into an array of lines

        totalSum = 0;
        linesArray = splitlines(textAreaValueStrArray);

        player_count = length(linesArray);

        team_1_count = 0;

        team_2_count = 0;

        team1_total = 0;

        team2_total = 0;
        
        sorted_ratings = sort(linesArray,'descend');

        disp(sorted_ratings);
        
        team1 = {};
        team2 = {};
    
        % Iterate through sorted ratings and assign players to teams alternately
        for i = 1:length(sorted_ratings)
        if mod(i, 2) == 1
            team1 = [team1, sorted_ratings(i)];
        else
            team2 = [team2, sorted_ratings(i)];
        end
        end

        for i=1:length(team1)
           numericValues_1 = str2double(regexp(team1{i}, '\d+(\.\d+)?', 'match'));
           team1_total = team1_total + sum(numericValues_1);
        end
        
        %disp(team1_total);
        

        app.Team1TextArea.Value = team1;

        new_rate = team1_total / length(team1);

        %disp(new_rate);

        for i=1:length(team2)
            numericValues_2 = str2double(regexp(team2{i}, '\d+(\.\d+)?', 'match'));
            team2_total = team2_total + sum(numericValues_2);
        end
        
        disp(team2_total);
        
        new_rate_2 = team2_total / length(team2);

        %disp(new_rate_2);
        %disp(numericValues_1);

        %%% combination kısmı 
        player_count = length(linesArray);
        disp(length(linesArray));
        
        if mod(player_count,2) == 0
            team_1_count = player_count/2;
            team_2_count = player_count/2;
        else
            team_1_count = ((player_count-1)/2);
            team_2_count = player_count - team_1_count;

        end
        
        avg_team1 = team1_total / team_1_count;

        avg_team2 = team2_total / team_2_count;

        app.Team1AvgEditField.Value = avg_team1;

        app.Team2AvgEditField.Value = avg_team2;

        app.NumberofPlayersEditField_2.Value = team_2_count;

        app.NumberofPlayersEditField.Value = team_1_count;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'48'; '78'; '9'; '66'; '37'; '92'; '64'; '49'; '72'; '38'; '5'; '12'; '41'; '4'; '66'; '62'; '93'; '70'; '76'; '15'; '29'; '44'; '67'; '74'; '17'; '32'; '87'; '30'; '67'; '21'; '52'; '15'; '26'; '77'; '15'; '40'; '35'; '85'; '64'; '81'; '87'; '36'; '90'; '66'; '27'; '14'; '87'; '73'; '80'; '65'; '76'; '41'; '7'; '11'; '89'; '82'; '84'; '91'; '75'; '23'; '37'; '33'; '48'; '95'; '55'; '84'; '95'; '45'; '2'; '40'; '6'; '28'; '81'; '22'; '10'; '69'; '9'; '31'; '49'; '81'; '80'; '34'; '57'; '24'; '3'; '49'; '29'; '42'; '13'; '98'; '28'; '74'; '24'; '2'; '19'; '7'; '68'; '40'; '2'; '17'; '92'; '36'; '30'; '75'; '38'; '22'; '74'; '22'; '36'; '82'; '45'; '4'; '25'};
            app.Team1TextAreaLabel = uilabel(app.UIFigure);
            app.Team1TextAreaLabel.Text = 'Team 1';
            app.Team1TextArea = uitextarea(app.UIFigure);
            app.Team2TextAreaLabel = uilabel(app.UIFigure);
            app.Team2TextAreaLabel.Text = 'Team 2';
            app.Team2TextArea = uitextarea(app.UIFigure);
            app.Team1AvgEditFieldLabel = uilabel(app.UIFigure);
            app.Team1AvgEditFieldLabel.Text = 'Team 1 Avg';
            app.Team1AvgEditField = uieditfield(app.UIFigure, 'numeric');
            app.Team2AvgEditFieldLabel = uilabel(app.UIFigure);
            app.Team2AvgEditFieldLabel.Text = 'Team 2 Avg';
            app.Team2AvgEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPlayersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPlayersEditFieldLabel.Text = 'Number of Players';
            app.NumberofPlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPlayersEditField_2Label = uilabel(app.UIFigure);
            app.NumberofPlayersEditField_2Label.Text = 'Number of Players';
            app.NumberofPlayersEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q4_basic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end