classdef q7_R2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    
    methods (Access = private)
        
            function  checkMagicNumbers(app, parameters)
                magicNumbers = {};
                if iscell(parameters)
                    % Convert cell array to structure
                    parameters = struct(parameters{:});
                end
            % Specify your criteria for identifying magic numbers
            % For example, you can check if a parameter is a numeric constant
                for paramName = fieldnames(parameters)
                    value = parameters.(paramName{1});

                    if isnumeric(value) && numel(value) == 1
                    magicNumber = [paramName{1}, ': ', num2str(value)];
                    magicNumbers = [magicNumbers; magicNumber];
                    end
                end
                
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %{
            close_system('Kitchen_Lib');
            load_system('untitled.slx');
            %%disp(find_system);
            blocks = {};

            blocks = find_system;
            disp(blocks);
            disp(blocks(2));
            %}
             % Get the Simulink model file name (update with your file path)
           fileName = 'deneme';
           disp(fileName);

            % Load the Simulink model
            load_system(fileName);

            % Get all blocks in the model
            allBlocks = find_system(fileName, 'SearchDepth', 1, 'Type', 'Block');
            disp(allBlocks);
            % Iterate through each block

            disp(length(allBlocks));
            results = {};
            magicNumbers = {};
            blockParams = {};
            for i = 1:length(allBlocks)
                blockHandle = allBlocks(i);
                disp("girdi");
                disp(blockHandle);
                % Get parameters of the block
                blockParams_11 = get_param(blockHandle, 'ObjectParameters');
                
                blockParams = [blockParams; blockParams_11];
                h = getSimulinkBlockHandle(blockHandle,true);
                z = get_param('deneme','Constant');
                display(z);
                display(h);
                %%disp(blockParams);
                % Check for magic numbers
                
                %%magicNumbers = app.checkMagicNumbers(blockParams);

                
            end
            
            disp(class(allBlocks));
            %{
            for i = 1:length(allBlocks)
                blockHand = allBlocks(i);
                disp("burda");
                dialog_11 = get_param(fileName,'AND');
                disp(dialog_11);
            end
            %}


                %%disp(length(blockParams));
                %%disp(blockParams);
                %%value = get_param(blockParams{6},'Constant');
                %%disp(value);
                
                %%disp(blockParams_2)
                %{
                value2 = getfield(blockParams,{1},'Mask');
                disp(value2);
               if iscell(blockParams)
                   % Convert cell array to structure
                    blockParams = struct(blockParams{:});
                    disp("cell");
               end
               disp(blockParams);
               value = getfield(blockParams,{1},'Mask');
               disp(value);
               for paramName = fieldnames(blockParams)
                    value = blockParams.(paramName{1});
                    disp("burada");
                    if isnumeric(value) && numel(value) == 1
                    magicNumber = [paramName{1}, ': ', num2str(value)];
                    magicNumbers = [magicNumbers; magicNumber];
                    %%disp("burada");
                    end
                end
            
            if ~isempty(magicNumbers)
                    results = [results; magicNumbers];
            end
            %%disp(results);
            

            % Unload the Simulink model
            close_system(fileName, 0);
                %}
                
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q7_R2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end