classdef q8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        EditField3       matlab.ui.control.EditField
        EditField3Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        EditFieldLabel   matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function replacedBlocks = performLibrarySwap(~,modelPath,blockToReplace,replacementBlock)
             load_system(modelPath);

    % Find all instances of the blockToReplace in the model
    blockInstances = find_system(modelPath, 'BlockType', 'SubSystem', 'ReferenceBlock', blockToReplace);

    % Initialize the list of replaced block paths
    replacedBlocks = cell(numel(blockInstances), 1);

    % Iterate through each instance and replace the block
    for i = 1:numel(blockInstances)
        % Get the position of the block
        position = get_param(blockInstances{i}, 'Position');

        % Delete the block to be replaced
        delete_block(blockInstances{i});

        % Add the replacement block at the same position
        newBlock = add_block(replacementBlock, blockInstances{i});
        set_param(newBlock, 'Position', position);

        % Store the path of the replaced block
        replacedBlocks{i} = newBlock;
    end

    % Save the modified model
    save_system(modelPath);

    % Close the Simulink model
    close_system(modelPath, 0);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
              % Get the values from text boxes
            modelPath = app.EditField.Value;
            blockToReplace = app.EditField2.Value;
            replacementBlock = app.EditField3.Value;

            % Call a function to perform the block replacement and create Excel
            replacedBlocks = performLibrarySwap(app,modelPath, blockToReplace, replacementBlock);

            % Create an Excel file listing replaced block paths
            excelFileName = 'ReplacedBlocksList.xlsx';
            writetable(table(replacedBlocks), excelFileName);

            % Display a message
            msgbox(['Block replacement completed. Excel file created: ' excelFileName], 'Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end