classdef q8_R2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlacedBlockEditField         matlab.ui.control.EditField
        PlacedBlockEditFieldLabel    matlab.ui.control.Label
        ReplacedBlockEditField       matlab.ui.control.EditField
        ReplacedBlockEditFieldLabel  matlab.ui.control.Label
        ModelPathEditField           matlab.ui.control.EditField
        ModelPathEditFieldLabel      matlab.ui.control.Label
        WriteExcelButton             matlab.ui.control.Button
        ReplaceButton                matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.mdl;*.slx', 'Simulink Models'}, 'Select Simulink model');
            if isequal(file, 0)
                return; % User canceled the dialog
            end
            app.ModelPathEditField.Value = fullfile(path, file);
        end

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            modelPath = app.ModelPathEditField.Value;
            replacedBlock = app.ReplacedBlockEditField.Value;
            placedBlock = app.PlacedBlockEditField.Value;
        end

        % Button pushed function: WriteExcelButton
        function WriteExcelButtonPushed(app, event)
            replacedBlock = app.ReplacedBlockEditField.Value;
            placedBlock = app.PlacedBlockEditField.Value;

            % Sample data (replace this with actual data)
            data = {
                replacedBlock, placedBlock, '/path/to/replaced_block';
                % Add more rows as needed
            };

            % Create Excel file
            [~, excelPath] = uiputfile('*.xlsx', 'Save Excel Log');
            if isequal(excelPath, 0)
                return; % User canceled the dialog
            end

            % Write data to Excel
            writetable(cell2table(data), excelPath, 'WriteVariableNames', false);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ReplaceButton = uibutton(app.UIFigure, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
            app.WriteExcelButton = uibutton(app.UIFigure, 'push');
            app.WriteExcelButton.ButtonPushedFcn = createCallbackFcn(app, @WriteExcelButtonPushed, true);
            app.WriteExcelButton.Text = 'WriteExcelButton';
            app.ModelPathEditFieldLabel = uilabel(app.UIFigure);
            app.ModelPathEditFieldLabel.Text = 'ModelPath';
            app.ModelPathEditField = uieditfield(app.UIFigure, 'text');
            app.ReplacedBlockEditFieldLabel = uilabel(app.UIFigure);
            app.ReplacedBlockEditFieldLabel.Text = 'ReplacedBlock';
            app.ReplacedBlockEditField = uieditfield(app.UIFigure, 'text');
            app.PlacedBlockEditFieldLabel = uilabel(app.UIFigure);
            app.PlacedBlockEditFieldLabel.Text = 'PlacedBlock';
            app.PlacedBlockEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q8_R2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end