classdef team_divider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea_2       matlab.ui.control.TextArea
        TextArea_2Label  matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        TextAreaLabel    matlab.ui.control.Label
        Button           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %% Player values and player count
            
            text_area = app.TextArea.Value;
            player_ratings = string(text_area);

            player_count = length(player_ratings);

            %disp(length(player_ratings));
            disp(player_count);
%% Player array structure for text area
            play_array = {};
            for i = 1:player_count
                % Extract numeric values using regular expression
                numericValues = str2double(regexp(player_ratings{i}, '\d+(\.\d+)?', 'match'));
                play_array = [play_array;numericValues];

            end
%% Team count divide 

        team_1_count = 0;
        team_2_count = 0;

        if mod(player_count,2) == 0
            team_1_count = player_count/2;
            team_2_count = player_count/2;
        else
            team_1_count = ((player_count-1)/2);
            team_2_count = player_count - team_1_count;

        end
            ar = [1:player_count];
            disp(ar);
            
            randomIndices = randperm(player_count);
            disp(randomIndices);


            C = randomIndices(1:team_1_count);
            disp(C);
            B = [ar ,C];

            ext = unique(B);


            disp(B);
            disp(length(B));
            rand_team1 = play_array(randomIndices(1:team_1_count));
            disp(ext);

            disp(rand_team1);


            % Number to be removed

            for i = 1:length(C)

                numberToRemove = C(i);
                
                % Use logical indexing to remove all occurrences of the number
                myArrayWithoutNumber = B(B ~= numberToRemove);
                
            end
                % Display the result
                disp(myArrayWithoutNumber);


            %% Team 1 to Text Area
            app.TextArea_2.Value = string(rand_team1);

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'12'; '24'; '34'; '10'; '11'; '13'; '18'; '98'; '89'; '95'};
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = team_divider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end