classdef q1_R2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        Button      matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
    end

    
    properties (Access = public)

        forward_count = 0;
        back_count = 0;
        total_count = 0;
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            %app.forward_count = app.forward_count + 1;
            %app.total_count = app.total_count + 1; 
            if (0<app.total_count) && (app.total_count <=3) 
            app.total_count = app.total_count + 1; 
            elseif app.total_count >= 0
                app.total_count = 1;
            else
                app.total_count = 1;
            end
            if app.total_count == 1
                imshow("windrose.png");
            elseif app.total_count == 2
                imshow('unnamed.png','Parent',app.UIAxes);
            elseif app.total_count == 3
                imshow('cheese.png','Parent',app.UIAxes);
            else
                app.forward_count = 0;
                app.total_count = 1;
                imshow('windrose.png','Parent',app.UIAxes);
            

            end
            imread('windrose.png');
            
            disp(app.total_count);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %if ekle total count max 0 olması için devam 
            if (0<app.total_count) && (app.total_count <= 3) 
            app.total_count = app.total_count - 1; 
            elseif app.total_count <= 0
                app.total_count = 1;
            else
                app.total_count = 1;
            end

            if app.total_count == 1
                imshow('windrose.png','Parent',app.UIAxes);
            elseif app.total_count == 2
                imshow('unnamed.png','Parent',app.UIAxes);
            elseif app.total_count == 3
                imshow('cheese.png','Parent',app.UIAxes);
            end
            disp(app.total_count);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_2.Icon = fullfile(pathToMLAPP, 'back.png');
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button.Icon = fullfile(pathToMLAPP, 'forward2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q1_R2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end