classdef q2_final_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        PathLabel                 matlab.ui.control.Label
        ExtensionsEditField       matlab.ui.control.EditField
        ExtensionsEditFieldLabel  matlab.ui.control.Label
        PathEditField             matlab.ui.control.EditField
        SelectFilesButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            % Allow the user to select both folder and files
            [files, path] = uigetfile('*.*', 'Select Files', 'MultiSelect', 'on');
            
            % Check if the user canceled file selection
            if isequal(files, 0)
                disp('User selected Cancel');
            else

                    if ~iscell(files)
                        files = {files};
                    end
                
                folder = fileparts(fullfile(path, files{1}));
            
                % Update EditField with the selected folder
                app.PathEditField.Value = string(folder);
            
                % Extract file extensions from the selected file(s)
                ext = cell(size(files));
                %numel number of array elements
                for i = 1:numel(files)
                    [~, ~, ext{i}] = fileparts(files{i});
                end
                ext = unique(ext);
                            
                % Update EditField_2 with the selected file extensions
                app.ExtensionsEditField.Value = strjoin(ext, ', ');
                % Print the full paths of selected files (for debugging)
                % Create a zip file one level above the selected folder
                prompt = {'Zip File Name'};
                dlgtitle = 'Input';
                answer = inputdlg(prompt,dlgtitle);
                s = strcat(answer(1),'zip');
                display(s);
                zipFileName = fullfile(fileparts(folder), char(s));
            
                % Change the current directory to the folder
                cd(folder);
            
                % Zip the selected files using a cell array of file paths
                zip(zipFileName, files);
                
                % Display a success message
                msgbox('Zip file created successfully!' );
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectFilesButton = uibutton(app.GridLayout, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.PathEditField = uieditfield(app.GridLayout, 'text');
            app.ExtensionsEditFieldLabel = uilabel(app.GridLayout);
            app.ExtensionsEditFieldLabel.Text = 'Extensions';
            app.ExtensionsEditField = uieditfield(app.GridLayout, 'text');
            app.PathLabel = uilabel(app.GridLayout);
            app.PathLabel.Text = 'Path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q2_final_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end