classdef q4_final_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        T2AverageEditField            matlab.ui.control.NumericEditField
        T2AverageEditFieldLabel       matlab.ui.control.Label
        T2PlayerEditField             matlab.ui.control.NumericEditField
        T2PlayerEditFieldLabel        matlab.ui.control.Label
        T1AverageEditField            matlab.ui.control.NumericEditField
        T1AverageEditFieldLabel       matlab.ui.control.Label
        T1PlayerEditField             matlab.ui.control.NumericEditField
        T1PlayerEditFieldLabel        matlab.ui.control.Label
        AverageEditField              matlab.ui.control.NumericEditField
        AverageEditFieldLabel         matlab.ui.control.Label
        TotalPlayerEditField_2        matlab.ui.control.NumericEditField
        PlayerRatingsTextAreaLabel_3  matlab.ui.control.Label
        Team2                         matlab.ui.control.TextArea
        PlayerRatingsTextAreaLabel_2  matlab.ui.control.Label
        Team1                         matlab.ui.control.TextArea
        PlayerRatingsTextArea         matlab.ui.control.TextArea
        PlayerRatingsTextAreaLabel    matlab.ui.control.Label
        TotalPlayerEditField          matlab.ui.control.NumericEditField
        TotalPlayerEditFieldLabel     matlab.ui.control.Label
        Button                        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Number of players
            text_area_first = app.PlayerRatingsTextArea.Value;

            text_area_string = string(text_area_first);

            player_ratings = splitlines(text_area_string);

            ratings = double(player_ratings);

            %disp(ratings);
            
            % Generate random ratings between 1 and 100 for each player
            %ratings = randi([1, 100], 1, team_count);
            %ratings = [1,2,3,4,5,7,6,8,9,10,11];
            total_ratings = sum(ratings);

           player_count = length(ratings);

           app.TotalPlayerEditField_2.Value = player_count;

           app.AverageEditField.Value = (total_ratings / player_count);
            %% Team divide

            team_1_count = 0;
            team_2_count = 0;


            if mod(player_count,2) == 0
                team_1_count = player_count/2;
                team_2_count = player_count/2;
            else
                team_1_count = ((player_count+1)/2);
                team_2_count = player_count - team_1_count;
    
            end

            disp(team_1_count);
            disp(team_2_count);
            % Display the generated ratings
            %disp('Random Ratings:');
            %disp(ratings);
            %disp(ratings(1));

            C = nchoosek(ratings,(team_1_count));
            %% Display Section
            disp(C);
            %disp(length(C));
            %disp(C(1:team_count));
            %disp(C(50,:));
            %disp(size(C));

            %% Row Values Sum 
            %s=sum(C(50,:));
            %disp(s);

            length_comb = length(C);

            row_values = [];
            sira = [];
            for i=1: length_comb
                sira{i} = C(i,:);
                row_values{i} = sum(C((i),:));
            end

            %disp(row_values);
            %disp(class(row_values));
            %disp(class(ratings));
            %disp(length(row_values));
            %disp(row_values(1));

            %disp(sira);
            T = cell2mat(row_values);
            %disp(class(T));
            sorted_ratings = sort(T,'ascend');
            
            %disp(sorted_ratings);
            %% takım average ve sorted ratıngsten secme 

            player_avg = sum(ratings) / (2);

            %disp(player_avg);
            
            %% takımdaki oyunculara karar verme 
            
            avg_dif = [];
           
            for i = 1:length(C)
                avg_dif{i} = player_avg - row_values{i};
                
            end
            
            Z = cell2mat(avg_dif);
            l = abs(Z);
            min_dif = min(l);
            %disp(avg_dif);
            %disp(min(l));
            team1 = [];

            for i =1: length(C)
                if avg_dif{i} == min_dif  
                    disp(i);
                    team1 = sira{i};
                    break
                end
            end
            %% Team 1 Test Area
            
            %disp(team1);
            %disp(class(team1));

            team_1_1 = string(team1);
            app.Team1.Value= team_1_1;
            
            team1_total = sum(team1); 

            app.T1PlayerEditField.Value = team_1_count;

            app.T1AverageEditField.Value = (team1_total / team_1_count);


    %% Team 2 text area        
            team2 = ratings;
            
            for i = 1:length(team1)
                index = find(team2 == team1(i), 1);
                if ~isempty(index)
                    team2(index) = [];
                end
            end


            team_2_2 = string(team2);

            app.Team2.Value = team_2_2;

            team2_total = sum(team2);

            app.T2PlayerEditField.Value = team_2_count;

            app.T2AverageEditField.Value = (team2_total / team_2_count);


            % Generate all possible combinations of teams
            %{
            allCombinations = {};
            for k = 1:numPlayers/2
                combinations = nchoosek(1:numPlayers, k*2);
                allCombinations = [allCombinations; combinations];
            end
            %}
            %{
            % Display all possible combinations with corresponding ratings
            disp('All Possible Combinations of Teams and Ratings:');
            for i = 1:size(allCombinations, 1)
                teamIndices = allCombinations(i, :);
                teamRatings = ratings(teamIndices{1});
                fprintf('Team %d: %s\n', i, num2str(teamRatings));
            end
            %}
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TotalPlayerEditFieldLabel = uilabel(app.UIFigure);
            app.TotalPlayerEditFieldLabel.Text = 'Total Player';
            app.TotalPlayerEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlayerRatingsTextAreaLabel = uilabel(app.UIFigure);
            app.PlayerRatingsTextAreaLabel.Text = 'Player Ratings';
            app.PlayerRatingsTextArea = uitextarea(app.UIFigure);
            app.PlayerRatingsTextArea.Value = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.Team1 = uitextarea(app.UIFigure);
            app.PlayerRatingsTextAreaLabel_2 = uilabel(app.UIFigure);
            app.PlayerRatingsTextAreaLabel_2.Text = 'Team 1';
            app.Team2 = uitextarea(app.UIFigure);
            app.PlayerRatingsTextAreaLabel_3 = uilabel(app.UIFigure);
            app.PlayerRatingsTextAreaLabel_3.Text = 'Team 2';
            app.TotalPlayerEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AverageEditFieldLabel = uilabel(app.UIFigure);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.T1PlayerEditFieldLabel = uilabel(app.UIFigure);
            app.T1PlayerEditFieldLabel.Text = 'T1 Player';
            app.T1PlayerEditField = uieditfield(app.UIFigure, 'numeric');
            app.T1AverageEditFieldLabel = uilabel(app.UIFigure);
            app.T1AverageEditFieldLabel.Text = 'T1 Average';
            app.T1AverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.T2PlayerEditFieldLabel = uilabel(app.UIFigure);
            app.T2PlayerEditFieldLabel.Text = 'T2 Player';
            app.T2PlayerEditField = uieditfield(app.UIFigure, 'numeric');
            app.T2AverageEditFieldLabel = uilabel(app.UIFigure);
            app.T2AverageEditFieldLabel.Text = 'T2 Average';
            app.T2AverageEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q4_final_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end