classdef q5_final_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TotalParametersEditField       matlab.ui.control.NumericEditField
        TotalParametersEditFieldLabel  matlab.ui.control.Label
        TotalOutputsEditField          matlab.ui.control.NumericEditField
        TotalOutputsEditFieldLabel     matlab.ui.control.Label
        TotalInputsEditField           matlab.ui.control.NumericEditField
        TotalInputsEditFieldLabel      matlab.ui.control.Label
        ParametersLabel                matlab.ui.control.Label
        TextArea_3                     matlab.ui.control.TextArea
        OutputsLabel                   matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        InputsLabel                    matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ParseButton                    matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        PathEditField                  matlab.ui.control.EditField
        PathEditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        folder; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            filter = {'*.m*'};
            [file,path] = uigetfile(filter);
            
            app.folder = fullfile(path,file) ;
            

            app.PathEditField.Value = app.folder;


        end

        % Button pushed function: ParseButton
        function ParseButtonPushed(app, event)
                    
            lines = readlines(app.folder);

            %%disp(lines);
            
            add_class = {};
            inputs = {};
            outputs = {};
            parameters = {};


            for i = 1: length(lines)
                 output_file = regexp(lines{i}, '\w*AddClassification','match');
                 
                 %disp(output_file);
                 %disp(length(output_file));

                if (length(output_file) == 1)
                    add_class = [add_class;lines{i}];
                end
            end

            
            for i = 1: length(add_class)
                search_in = regexp(add_class{i}, 'w*Input','match');
                
                if (length(search_in) == 1)
                    inputs = [inputs;add_class{i}];
                    
                    
                end
            end


            inputs_2 ={};
            
            for i = 1:length(inputs)
                C = strsplit(inputs{i},'.');
                inputs_2{i} = [C{1}];
                
            end

            
            for i = 1:length(add_class)
              search_in_output = regexp(add_class{i}, 'w*Output','match');
              if (length(search_in_output) == 1)
                  outputs = [outputs ; add_class{i}];
                  
              end
            end

            outputs_2 ={};
           
            for i = 1:length(outputs)
                C = strsplit(outputs{i},'.');
                outputs_2{i} = [C{1}];
                
            end

            for i = 1:length(add_class)
              search_in_output = regexp(add_class{i}, 'w*Local','match');
              if (length(search_in_output) == 1)
                  parameters = [parameters;add_class{i}];
                  
              end
            end

            parameters_2 ={};
           
            for i = 1:length(parameters)
                C = strsplit(parameters{i},'.');
                parameters_2{i} = [C{1}];
                
            end
            %%
            
            for i = 1:length(parameters_2)

                str = parameters_2{i};

                last_two = str(end-1:end);
                
                if (last_two == '_C')
                    str = str(1:end-2);
                    parameters_2{i} = str;
                end
            end
            
            %disp(parameters_2);


            app.TextArea.Value = inputs_2;
            app.TextArea_2.Value = outputs_2;

            app.TextArea_3.Value = parameters_2;

            app.TotalInputsEditField.Value = length(inputs_2);

            app.TotalOutputsEditField.Value = length(outputs_2);

            app.TotalParametersEditField.Value = length(parameters_2);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ParseButton = uibutton(app.UIFigure, 'push');
            app.ParseButton.ButtonPushedFcn = createCallbackFcn(app, @ParseButtonPushed, true);
            app.ParseButton.Text = 'Parse';
            app.TextArea = uitextarea(app.UIFigure);
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.TotalInputsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalInputsEditFieldLabel.Text = 'Total Inputs';
            app.TotalInputsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalOutputsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalOutputsEditFieldLabel.Text = 'Total Outputs';
            app.TotalOutputsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalParametersEditFieldLabel = uilabel(app.UIFigure);
            app.TotalParametersEditFieldLabel.Text = 'Total Parameters';
            app.TotalParametersEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q5_final_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end