classdef q7_final_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ListOfMagicNumbersLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        ModelPathEditField       matlab.ui.control.EditField
        ModelPathEditFieldLabel  matlab.ui.control.Label
        Button                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [files, path] = uigetfile('*.*', 'Select Files', 'MultiSelect', 'on');
            
            % Check if the user canceled file selection
            if isequal(files, 0)
                disp('User selected Cancel');
            else

             if ~iscell(files)
                  files = {files};
             end
            
             disp(files{1});
             folder = fileparts(fullfile(path, files{1}));
             filename = fullfile(path,files{1});
             [filepath,name,ext] = fileparts(filename);

             disp(name);
            end

            app.ModelPathEditField.Value = folder;

            addpath(filepath);
            load_system(name);


            %%Find the constants

            constants = find_system(name, 'BlockType', 'Constant');
            gains = find_system(name,'BlockType','Gain');

            disp(constants);
            % Extract signal names from the lines
            const_param = get_param(constants, 'Name');
            disp(gains);
            disp(const_param);
            add_class = {};
            for i = 1: length(constants)
                output_file = regexp(constants{i}, '\w*Constant','match');
                    
                if (length(output_file) == 1)
                    add_class = [add_class;constants{i}];
                end
            end

            disp(add_class);
            another_class = {};
            for i = 1: length(gains)
                output_file = regexp(gains{i}, '\w*Gain','match');
                    
                if (length(output_file) == 1)
                    add_class = [add_class;gains{i}];
                end
            end
            disp(add_class);


           app.TextArea.Value = add_class;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ModelPathEditFieldLabel = uilabel(app.UIFigure);
            app.ModelPathEditFieldLabel.Text = 'Model Path ';
            app.ModelPathEditField = uieditfield(app.UIFigure, 'text');
            app.TextArea = uitextarea(app.UIFigure);
            app.ListOfMagicNumbersLabel = uilabel(app.UIFigure);
            app.ListOfMagicNumbersLabel.Text = 'List Of Magic Numbers';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q7_final_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end