classdef q8_deneme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NewEditField            matlab.ui.control.EditField
        NewEditFieldLabel       matlab.ui.control.Label
        ReplacedEditField       matlab.ui.control.EditField
        ReplacedEditFieldLabel  matlab.ui.control.Label
        PathEditField           matlab.ui.control.EditField
        PathEditFieldLabel      matlab.ui.control.Label
        ReplaceButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            %%Path selection
            [files, path] = uigetfile('*.*', 'Select Files', 'MultiSelect', 'on');
            
            % Check if the user canceled file selection
            if isequal(files, 0)
                disp('User selected Cancel');
            else

             if ~iscell(files)
                  files = {files};
             end
            
             disp(files{1});
             folder = fileparts(fullfile(path, files{1}));
             filename = fullfile(path,files{1});
             [filepath,name,ext] = fileparts(filename);

             disp(name);
             
             excel_name_1 = name + "_changed.xlsx";
             excel_name = fullfile(filepath,excel_name_1);
             % Update EditField with the selected folder
             app.PathEditField.Value = string(folder);
            end


            addpath(folder);
  
            replace = app.ReplacedEditField.Value;


            new = app.NewEditField.Value;
            
            load_system(name);
            

            allBlocks = find_system(name,'SearchDepth', 1, 'Type', 'Block');
            disp(allBlocks);
            rep_names = replace_block(name,replace,new);
            disp(rep_names);

            disp("değişti");
            disp("remove");
            writecell(rep_names,excel_name);
            rmpath(folder);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReplaceButton = uibutton(app.UIFigure, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.ReplacedEditFieldLabel = uilabel(app.UIFigure);
            app.ReplacedEditFieldLabel.Text = 'Replaced';
            app.ReplacedEditField = uieditfield(app.UIFigure, 'text');
            app.NewEditFieldLabel = uilabel(app.UIFigure);
            app.NewEditFieldLabel.Text = 'New';
            app.NewEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q8_deneme

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end