classdef q8_final_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NewBlockEditField            matlab.ui.control.EditField
        NewBlockEditFieldLabel       matlab.ui.control.Label
        ReplacedBlockEditField       matlab.ui.control.EditField
        ReplacedBlockEditFieldLabel  matlab.ui.control.Label
        ModelPathEditField           matlab.ui.control.EditField
        ModelPathEditFieldLabel      matlab.ui.control.Label
        ReplaceButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            %%Path selection
            [files, path] = uigetfile('*.*', 'Select Files', 'MultiSelect', 'on');
            
            % Check if the user canceled file selection
            if isequal(files, 0)
                disp('User selected Cancel');
            else

             if ~iscell(files)
                  files = {files};
             end
            
             disp(files{1});
             folder = fileparts(fullfile(path, files{1}));
             filename = fullfile(path,files{1});
             [filepath,name,ext] = fileparts(filename);

             disp(name);             
             excel_name_1 = name + "_changed_blocks.xlsx";
             excel_name = fullfile(filepath,excel_name_1);
             % Update EditField with the selected folder
             app.ModelPathEditField.Value = string(folder);
            end


            addpath(folder);
  
            replace = app.ReplacedBlockEditField.Value;


            new = app.NewBlockEditField.Value;
            
            load_system(name);
            
            set_param(bdroot(name),'Lock','off');
            allBlocks = find_system(name,'SearchDepth', 1, 'Type', 'Block');
            disp(allBlocks);
            rep_names = replace_block(name,replace,new);
            disp(rep_names);

            disp("değişti");
            disp("remove");
            writecell(rep_names,excel_name);
            rmpath(folder);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReplaceButton = uibutton(app.UIFigure, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
            app.ModelPathEditFieldLabel = uilabel(app.UIFigure);
            app.ModelPathEditFieldLabel.Text = 'Model Path';
            app.ModelPathEditField = uieditfield(app.UIFigure, 'text');
            app.ReplacedBlockEditFieldLabel = uilabel(app.UIFigure);
            app.ReplacedBlockEditFieldLabel.Text = 'Replaced Block';
            app.ReplacedBlockEditField = uieditfield(app.UIFigure, 'text');
            app.NewBlockEditFieldLabel = uilabel(app.UIFigure);
            app.NewBlockEditFieldLabel.Text = 'New Block';
            app.NewBlockEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q8_final_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end